classdef SignalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ButtonGroup_2            matlab.ui.container.ButtonGroup
        PMButton                 matlab.ui.control.RadioButton
        FMButton                 matlab.ui.control.RadioButton
        AMButton                 matlab.ui.control.RadioButton
        Lamp_2                   matlab.ui.control.Lamp
        Lamp                     matlab.ui.control.Lamp
        RMSEditField             matlab.ui.control.EditField
        RMSEditFieldLabel        matlab.ui.control.Label
        STDerrorEditField        matlab.ui.control.EditField
        STDerrorEditFieldLabel   matlab.ui.control.Label
        MeanEditField            matlab.ui.control.EditField
        MeanEditFieldLabel       matlab.ui.control.Label
        PeakPeakEditField        matlab.ui.control.EditField
        PeakPeakEditFieldLabel   matlab.ui.control.Label
        PeakEditField            matlab.ui.control.EditField
        PeakEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.EditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        Slider_2                 matlab.ui.control.Slider
        Slider_2Label            matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        Label_2                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button_7                 matlab.ui.control.RadioButton
        Button_6                 matlab.ui.control.RadioButton
        Button_5                 matlab.ui.control.RadioButton
        Button_4                 matlab.ui.control.RadioButton
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        myt; %时钟中断
        type; %信号类型
        Fs; %采样频率
        N; %采样长度；
        wave; %信号数组
        t; %采样数组
        A; %信号幅值
        F; %信号频率
        P; %信号相位
        Fd % Description
        typetp
        waveAm
        waveFm
        wavePm
        dewave
    end

    methods (Access = private)

        function results = mySignal(app,type,A,F,P)
            dt=1.0/app.Fs;
            app.t=0:dt:app.N*dt;
            p0=P*pi/180;
            if type==0
                app.wave=A*sin(2*3.14*F*app.t+p0);
            end
            if type==1
                app.wave=A*square(2*3.14*F*app.t+p0);
            end
            if type==2
                app.wave=A*sawtooth(2*3.14*F*app.t+p0,0.5);
            end
            if type==3
                app.wave=0.2*A*randn(1,app.N+1);
            end
            tiaopin(app);
        end

        function results = drawSignal(app)
            plot(app.UIAxes,app.t,app.wave,'LineWidth',1.5);
            T1=0.4*app.N/app.Fs;
            xlim(app.UIAxes,[0,T1]);
            ylim(app.UIAxes,[-1,1]);
            grid(app.UIAxes,'on');
            
            if app.typetp == 0
                plot(app.UIAxes2,app.t,app.waveAm);
                plot(app.UIAxes3,app.t,app.dewave);              
            end
            if app.typetp == 1
                plot(app.UIAxes2,app.t,app.waveFm);
                plot(app.UIAxes3,app.t,app.dewave);
            end
            if app.typetp == 2
                plot(app.UIAxes2,app.t,app.wavePm);
                plot(app.UIAxes3,app.t,app.dewave);
            end
            
            xlim(app.UIAxes2,[0,T1]);
            ylim(app.UIAxes2,[-3,3]);
            grid(app.UIAxes2,'on');
            
            xlim(app.UIAxes3,[0,T1]);
            ylim(app.UIAxes3,[-1,1]);
            grid(app.UIAxes3,'on');
        end

        function results = myTimer(app)
            app.P=app.P+15;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
            %wavedetect(app);
        end


        function results = wavedetect(app)
            p=max(app.wave);
            q=min(app.wave);
            n=1;
            at=0.8*(p-q)+q;
            dt=1/app.Fs;
            for k=2:1:44100
                if (app.wave(k-1)<at && app.wave(k)<=at && app.wave(k+1)>at && app.wave(k+2)>at)
                    ti(n)=k+1*(at-app.wave(k))/(app.wave(k+1)-app.wave(k));
                    n=n+1;
                end
            end
            T=(ti(2)-ti(1))*dt;
            app.Fd=1/0/T;
            Q=360*(T-ti(1)*dt)/T;
            valueF=1/T;
            
            
            %RMS
            temp=0;
            for k=2:1:44100
                temp=(app.wave(k))^2+temp;
            end
            valueRMS=sqrt(temp/(44100-1));
            
            %peak
            valuePeak=max(abs(p),abs(q));
            valuePp=abs(p)+abs(q);
            
            %STD error
            valueSTDer=std(app.wave,0);
            
            %Mean
            valueMean=mean(app.wave);
            
            %output
            app.FrequencyEditField.Value=num2str(valueF);
            app.RMSEditField.Value=num2str(valueRMS);
            app.STDerrorEditField.Value=num2str(valueSTDer);
            app.PeakEditField.Value=num2str(valuePeak);
            app.PeakPeakEditField.Value=num2str(valuePp);
            app.MeanEditField.Value=num2str(valueMean);
        end


        function results = tiaopin(app)
            if app.typetp == 0
                app.waveAm=ammod(app.wave,400,app.Fs,0,1.3);
%                 plot(app.UIAxes2,app.t,app.waveAm);
                app.dewave=amdemod(app.waveAm,400,app.Fs,0,1.3);
%                 plot(app.UIAxes3,app.t,app.dewave);
            end
            if app.typetp == 1
                app.waveFm=fmmod(app.wave,400,app.Fs,200);
                app.dewave=fmdemod(app.waveFm,400,app.Fs,200);
            end
            if app.typetp == 2
                app.wavePm=pmmod(app.wave,400,app.Fs,3.14);
                app.dewave=pmdemod(app.wavePm,400,app.Fs,3.14);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myt = timer;
            app.myt.TimerFcn = @(~, ~)myTimer(app);
            app.myt.Period = 0.1;
            app.myt.ExecutionMode = 'fixedRate';
            app.type=0;
            app.Fs=44100;
            app.N=44100;
            app.A=0.8;
            app.F=10;
            app.P=0;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
            app.typetp=0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value=value;
            app.F=value;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.EditField_2.Value=value;
            app.A=value;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.Button_4
                app.type=0;
            end
            if selectedButton==app.Button_5
                app.type=1;
            end
            if selectedButton==app.Button_6
                app.type=2;
            end
            if selectedButton==app.Button_7
                app.type=3;
            end
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            start(app.myt);
            app.Lamp.Color=[0,1,0];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            stop(app.myt);
            app.Lamp.Color=[1,0,0];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Lamp_2.Color=[0,1,0];
            player=audioplayer(app.wave,app.Fs);
            playblocking(player);
            app.Lamp_2.Color=[1,0,0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.myt);
            delete(app)
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if selectedButton == app.AMButton
                app.typetp=0;
            end
            if selectedButton == app.FMButton
                app.typetp=1;
            end
            if selectedButton == app.PMButton
                app.typetp=2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '停止';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '播放';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '波形';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '正弦波';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '方波';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '三角波';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '白噪声';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '频率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Value = 20;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '幅值';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 0.8;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '频率';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '幅值';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Slider_2.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Slider_2.Value = 0.8;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditField.Editable = 'off';
            app.PeakEditFieldLabel = uilabel(app.UIFigure);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.UIFigure, 'text');
            app.PeakEditField.Editable = 'off';
            app.PeakPeakEditFieldLabel = uilabel(app.UIFigure);
            app.PeakPeakEditFieldLabel.Text = 'Peak-Peak';
            app.PeakPeakEditField = uieditfield(app.UIFigure, 'text');
            app.PeakPeakEditField.Editable = 'off';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.UIFigure, 'text');
            app.MeanEditField.Editable = 'off';
            app.STDerrorEditFieldLabel = uilabel(app.UIFigure);
            app.STDerrorEditFieldLabel.Text = 'STD error';
            app.STDerrorEditField = uieditfield(app.UIFigure, 'text');
            app.STDerrorEditField.Editable = 'off';
            app.RMSEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.UIFigure, 'text');
            app.RMSEditField.Editable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '模式';
            app.AMButton = uiradiobutton(app.ButtonGroup_2);
            app.AMButton.Text = 'AM';
            app.AMButton.Value = true;
            app.FMButton = uiradiobutton(app.ButtonGroup_2);
            app.FMButton.Text = 'FM';
            app.PMButton = uiradiobutton(app.ButtonGroup_2);
            app.PMButton.Text = 'PM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end