classdef morse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorseCodeUIFigure           matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SendTab                     matlab.ui.container.Tab
        LoadButton                  matlab.ui.control.Button
        CarrierFrequencySpinner     matlab.ui.control.Spinner
        CarrierFrequencyLabel       matlab.ui.control.Label
        WordsperMinuteSpinner       matlab.ui.control.Spinner
        WordsperMinuteSpinnerLabel  matlab.ui.control.Label
        MorseTextArea               matlab.ui.control.TextArea
        MorseTextAreaLabel          matlab.ui.control.Label
        TextTextArea                matlab.ui.control.TextArea
        TextTextAreaLabel           matlab.ui.control.Label
        SendButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ReceiveTab                  matlab.ui.container.Tab
        SaveButton                  matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        ReceivedMorseTextArea       matlab.ui.control.TextArea
        ReceivedMorseTextAreaLabel  matlab.ui.control.Label
        ReceivedTextTextArea        matlab.ui.control.TextArea
        ReceivedTextTextAreaLabel   matlab.ui.control.Label
        ListenSwitch                matlab.ui.control.Switch
        ListenSwitchLabel           matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        closing_req = false;
        unused_times = [];
        last_sound_on = false;
        is_silent = true;
        ticks = 0;
        time_step;
        dit_dah_threshold;
        dah_space_threshold;
        message = [];
        morse_code = [];
    end
    
    properties(Constant, Access = private)
        dit = '.';
        dah = '-';
        wordsep = ' ';
        lettersep = '|';
        word_length = 50;
        sampling_rate = 45600;
        samples_per_frame = 255;
        vol_threshold = -50;
        flush_time = 500;
        noise_threshold = 1;
        unknown_char = '#';
        
        sdrCenterFrequency=91.2e6;
        sdrsampling_rate = 228e3;
        sdrsamples_per_frame = 255*5;
        FrequencyDeviation = 75e3; % 最大频偏
    end
        
    methods (Access = public)

        function wave = morse2wave(app, morse)
            app.SendButton.Enable = false;
            
            [b, a] = butter(4, app.CarrierFrequencySpinner.Value / (app.sampling_rate / 4));
            
            dit_duration = 60 / (app.WordsperMinuteSpinner.Value * app.word_length); 
            dah_duration = dit_duration * 3;
                      
            int_el_duration = dit_duration;
            short_duration = dah_duration;
            medium_duration = dit_duration * 7;
            int_el_samples = floor(int_el_duration  * app.sampling_rate);
            short_samples = floor(short_duration * app.sampling_rate);
            medium_samples = floor(medium_duration * app.sampling_rate);

            wave = [];
            t = 0;
            
            for i = 1:length(morse)
                if morse(i) == app.dit
                    dit_wave = app.morse_wave(t, dit_duration);
                    wave = [wave; dit_wave; zeros(int_el_samples, 1)];
                    t = t + dit_duration + int_el_duration;
                
                elseif morse(i) == app.dah
                    dah_wave = app.morse_wave(t, dah_duration);
                    wave = [wave; dah_wave; zeros(int_el_samples, 1)];
                    t = t + dah_duration + int_el_duration;
                
                elseif morse(i) == app.lettersep
                    wave = [wave; zeros(short_samples - int_el_samples, 1)];
                    t = t + short_duration;
                    
                elseif morse(i) == app.wordsep
                    wave = [wave; zeros(medium_samples - int_el_samples, 1)];
                    t = t + medium_duration;
                end
            end
            
            wave = filter(b, a, wave);
            player = audioplayer(wave, app.sampling_rate, 16);
            playblocking(player);
            app.SendButton.Enable = true;
        end
    
        function wave = morse_wave(app, start_time, duration)
            dt = 1/app.sampling_rate;
            wave = sin((start_time:dt:start_time + duration - dt) * 2 * pi * app.CarrierFrequencySpinner.Value)';
        end
    
        function message = morse_decode(app, morse)
        
            if isempty(morse)
                return;
            end
            
            current = '';
            message = '';
            
            for k = 1:length(morse)
                if morse(k) == app.dit || morse(k) == app.dah
                    current = [current, morse(k)];
                
                elseif morse(k) == app.lettersep
                    message = [message, app.morse_decode_char(current)];
                    current = '';
                
                elseif morse(k) == app.wordsep
                    message = [message, app.morse_decode_char(current), ' '];
                    current = '';
                
                else
                    warning(['Invalid Morse code "', morse(k), '" on message "', morse, '".']);
                end
            end
            if current
                message = [message, app.morse_decode_char(current)];
            end
        end
        
        function pretty = printable_morse(app, morse)
            pretty = strrep(morse, app.wordsep, '      ');
            pretty = strrep(pretty, app.lettersep, '  ');
            pretty = strrep(pretty, app.dah, '▬ ');
            pretty = strrep(pretty, app.dit, '● ');
        end
    
        function text = morse_decode_char(app, morse_ch)
        % Based on https://stackoverflow.com/questions/28045172/morse-code-converter-in-c#28046691
        % Uses a dichotomic search tree to parse each morse sign (* means no character
        % is mapped to that combination).
            if isempty(morse_ch)
                text = '';
                return;
            end
        
           morse_table.text = { ...
                'A','B','C','D','E','F','G','H', ...
                'I','J','K','L','M','N','O','P', ...
                'Q','R','S','T','U','V','W','X', ...
                'Y','Z', ...
                '0','1','2','3','4','5','6','7','8','9', ...
                'Ä','Á','CH','É','Ñ','Ö','Ü', ...
                '&','''','@',')','(',':',',','=','!', ...
                '.','-','+','"', '?','/', ...
                '<BK>', ...
                '<CL>','<CT>','<DO>', ...
                '<SK>','<SN>','<SOS>' ...
            };
            
            morse_table.morse = { ...
                '.-','-...','-.-.','-..','.','..-.','--.','....', ...
                '..','.---','-.-','.-..','--','-.','---','.--.', ...
                '--.-','.-.','...','-','..-','...-','.--','-..-', ...
                '-.--','--..', ...
                '-----','.----','..---','...--','....-','.....','-....','--...','---..','----.', ...
                '.-.-','.--.-','----', '..-..','--.--','---.','..--', ...
                '.-...','.----.','.--.-.','-.--.-', '-.--.', '---...','--..--','-...-','-.-.--', ...
                '.-.-.-','-....-','.-.-.','.-..-.', '..--..','-..-.', ...
                '-...-.-', ...
                '-.-..-..','-.-.-','-..---', ...
                '...-.-','...-.','...---...' ...
            };
            
            is_morse = strcmp(morse_ch, morse_table.morse);
            if any(is_morse)
                text = morse_table.text{is_morse};
            else
                text = app.unknown_char;
                 warning(['Invalid Morse sign "', morse_ch, '" on code.']);
            end
        end
        
        function morse = morse_encode(app, message) 
            % Encodes a message from ASCII to Morse code, with separators for letters and words
    
            if isempty(message)
                morse = '';
                return
            end
            
            morse_table.text = { ...
                'A','B','C','D','E','F','G','H', ...
                'I','J','K','L','M','N','O','P', ...
                'Q','R','S','T','U','V','W','X', ...
                'Y','Z', ...
                '0','1','2','3','4','5','6','7','8','9', ...
                'Ä','Á','CH','É','Ñ','Ö','Ü', ...
                '&','''','@',')','(',':',',','=','!', ...
                '.','-','+','"', '?','/', ...
                '<BK>', ...
                '<CL>','<CT>','<DO>', ...
                '<SK>','<SN>','<SOS>' ...
            };
            
            morse_table.morse = { ...
                '.-','-...','-.-.','-..','.','..-.','--.','....', ...
                '..','.---','-.-','.-..','--','-.','---','.--.', ...
                '--.-','.-.','...','-','..-','...-','.--','-..-', ...
                '-.--','--..', ...
                '-----','.----','..---','...--','....-','.....','-....','--...','---..','----.', ...
                '.-.-','.--.-','----', '..-..','--.--','---.','..--', ...
                '.-...','.----.','.--.-.','-.--.-', '-.--.', '---...','--..--','-...-','-.-.--', ...
                '.-.-.-','-....-','.-.-.','.-..-.', '..--..','-..-.', ...
                '-...-.-', ...
                '-.-..-..','-.-.-','-..---', ...
                '...-.-','...-.','...---...' ...
                };
            
            morse = '';
            k = 1;
            while k <= length(message)
                if message(k) == ' '
                    morse = [morse(1:end-1), app.wordsep];
                    k = k + 1;
                elseif message(k) == '<'
                    if (length(message) >= k+4) && (strcmp(message(k:k+4), '<SOS>'))
                        morse = [morse, morse_table.morse{strcmp('<SOS>', morse_table.text)}, app.lettersep];
                        k = k + 5;
                    elseif (length(message) >= k+3) && (strcmp(message(k+3), '>'))
                        is_morse = strcmp(message(k:k+3), morse_table.text);
                        if any(is_morse)
                            morse = [morse, morse_table.morse{is_morse}, app.lettersep];
                            k = k + 4;
                        else
                            warning(['Invalid prosign "' message(k:k+3) '" on message.']);
                            k = k + 4;
                        end
                        disp(k);
                    else
                        warning('Invalid character "<" on message.');
                        k = k + 1; 
                    end
                else   
                    is_morse = strcmp(message(k), morse_table.text);
                    if any(is_morse)
                        morse = [morse, morse_table.morse{is_morse}, app.lettersep];
                        k = k + 1;
                    else
                        k = k + 1;
                        warning(['Invalid character "' message(k) '" on message.']);
                    end
                end
            end

            morse = morse(1:end-1);
        end
        
        function listen_morse(app)
            %deviceReader = audioDeviceReader(app.sampling_rate, app.samples_per_frame);
            sosMatrixDeemph = coder.const(...
                [ 1.7272    0.9539    0.1820    1.0000    0.5459    0.1021
                0.0592   -0.0020    0.0021    1.0000   -0.0275    0.0347
                0.5002    0.4997         0    1.0000   -0.9432         0 ]);
            ScaleValuesDeemph = coder.const([0.5562    1.0000    1.0000    1.0000]');
            LowPassNumerator = coder.const(10^-4*[ ...
                1 1 0 -1 -3 -6 -10 -15 -22 -29 -35 -41 -43 -43 -39 -32 -21 -8 5 17 ...
                26 30 29 22 11 -3 -16 -27 -34 -34 -27 -15 2 19 34 43 44 36 20 -1 -24 ...
                -45 -57 -59 -49 -28 0 32  60 77  81 68 40 0 -43 -82 -108 -114 -97 -57 ...
                -1  62 120 160 172 148 89 2 -101 -200 -274 -304 -273 -172 -2  226 ...
                494 773 1033 1245 1383 1431 1383 1245 1033 773 494 226 -2 -172 -273 ...
                -304 -274 -200 -101 2 89 148 172 160 120 62 -1 -57 -97 -114 -108 -82 ...
                -43 0 40 68 81 77 60 32 0 -28 -49 -59 -57 -45 -24 -1 20 36 44 43 34 ...
                19 2 -15 -27 -34 -34 -27 -16 -3 11 22 29 30 26 17 5 -8 -21 -32 -39 ...
                -43 -43 -41 -35 -29 -22 -15 -10 -6 -3 -1 0 1  1 ...
                ]);
            % 调用去加重滤波器对象
            pDeEmphFilter = dsp.BiquadFilter(...
                'Structure',    'Direct form II', ...
                'SOSMatrix',    sosMatrixDeemph, ...
                'ScaleValues',  ScaleValuesDeemph);

            % 调用多级FIR码率转换器对象
            pRateConv228 = dsp.SampleRateConverter( ...
                'InputSampleRate', app.sdrsampling_rate, ...
                'OutputSampleRate', app.sdrsampling_rate, ...
                'Bandwidth', 125e3, ...
                'OutputRateTolerance', 1e-6);
            pAudioRateConv = dsp.SampleRateConverter('Bandwidth',30e3,...
                'InputSampleRate',  app.sdrsampling_rate, ...
                'OutputSampleRate', app.sampling_rate, ...
                'OutputRateTolerance', 1e-6);

            % 调用低通滤波器对象
            pLowPass = dsp.FIRFilter('Numerator', LowPassNumerator);
            % 调用接收机RTL-SDR的硬件接口对象
            rxsdr = comm.SDRRTLReceiver('0','CenterFrequency',app.sdrCenterFrequency,'SampleRate',app.sdrsampling_rate, ...
                'SamplesPerFrame',app.sdrsamples_per_frame,'EnableTunerAGC',true,'OutputDataType','double');
            %调用音频播放器对象
            player = audioDeviceWriter('SampleRate',app.sampling_rate);
            maxFreq = app.sampling_rate / 2;
            freqBins = (app.samples_per_frame+1) / 2;
            freqStep = maxFreq / freqBins;
            
            min_f = 500;
            max_f = 600;
            f = min(min_f, max_f);
            min_bin = min(max(round(f / freqStep), 1), freqBins) + 1;
            f = min(max(max_f, 0), maxFreq);
            max_bin = min(max(round(f / freqStep), 1), freqBins) + 1;
            
            x_frame = 0;
            dt = app.samples_per_frame / app.sampling_rate / 1000;
            max_frame = round(app.sampling_rate / app.samples_per_frame);
            x_pos = 0:dt:(max_frame*app.samples_per_frame-1) * dt;
            received_data = zeros(1, max_frame * app.samples_per_frame);
            LastSample = 0+0*1i; % LastSample为上一组的最后一个信号采样，为复数
            
            app.UIAxes2.YLim = [-1.2 1.2];
            app.UIAxes2.XLim = [0 (max_frame*app.samples_per_frame-1) * dt];
      
            while app.ListenSwitch.Value == '1' && ~app.closing_req
                %x = deviceReader();
                
                % 从RTL-SDR硬件中接收一帧信号
                x = rxsdr();

                % 信号初始解码,从载波中分离出原始信号(调频的本质是调相)
                phase =  angle(x .* conj([LastSample;x(1:end-1,:)]));
                LastSample = x(end,:);
                yDemod = (app.sdrsampling_rate / (2*pi*app.FrequencyDeviation)) .* phase;

                % 转换为中间采样率228kHz
                yDemodIntermediate228 = pRateConv228(yDemod);

                % 低通滤波器，用于分离出准音频信号（单声道）：
                yAudioIntermediate = pLowPass(yDemodIntermediate228);

                % 去加重,也就是广播电台发射信号时所进行的"预加重"的逆操作
                yFilt =  pDeEmphFilter(yAudioIntermediate);

                % 将中间采样率228kHz的信号转换为音频采样率
                yAudio = pAudioRateConv(yFilt);

                % 播放声音信号,
                % 本来打算采用更简单的"sound(yAudio,AudioSampleRate);",但是此法无法连续播放
                player(yAudio);
                
                %xdft = fft(x);
                xdft = fft(yAudio);
                psdx = (1/(app.sampling_rate*app.samples_per_frame)) * abs(xdft(1:freqBins+1)).^2;
                psdx(2:end-1) = 2*psdx(2:end-1);
                psdx = 10*log10(psdx);
                %received_data(x_frame*app.samples_per_frame + 1:(x_frame+1) * app.samples_per_frame) = x;
                received_data(x_frame*app.samples_per_frame + 1:(x_frame+1) * app.samples_per_frame) = yAudio;
                plot(app.UIAxes2, x_pos, received_data, 'b');
                app.process_record(mean(psdx(min_bin:max_bin)) > app.vol_threshold);
                drawnow limitrate;
                if x_frame < max_frame - 1
                    x_frame = x_frame + 1;
                else
                    x_frame = 0;
                end
            end
            %release(deviceReader);
            
            if app.closing_req
                app.ListenSwitch.Value = '1';
                app.delete();
            end
        end

        
        function process_record(app, is_on)
            if app.is_silent
                if ~is_on
                    return;
                else
                    app.is_silent = false;
                    app.ticks = 0;
                    app.last_sound_on = true;
                end
            end
            if is_on == app.last_sound_on
                app.ticks = app.ticks + 1;
                if app.ticks * app.time_step > app.flush_time
                    app.flush(is_on);
                    app.ticks = 0;
                end
            
            else
                app.flush(~is_on);
                app.last_sound_on = is_on;
                app.ticks = 1;
            end    
        end
        
        function flush(app, is_on)
            if is_on
                app.add_timing(app.ticks * app.time_step);
            else
                app.add_timing(-app.ticks * app.time_step);
            end
        end
    
        function add_timing(app, d)
            if d == 0
                return;
            end
            
            if ~isempty(app.unused_times)
                last = app.unused_times(end);
                if d * last > 0
                    app.unused_times(end) = [];
                    d = last + d;
                elseif abs(d) <= app.noise_threshold
                    app.unused_times(end) = [];
                    d = last - d;
                end
            end
            
            app.unused_times = [app.unused_times d];
            
            if (-d) >= app.dit_dah_threshold
                app.flush_timings();
            end
        end
    
        function flush_timings(app)
            if ~isempty(app.message) && (app.message(end) == app.wordsep)
                if app.unused_times(1) < 0
                    app.unused_times(1) = [];
                    app.is_silent = true;
                end
            end
            
            if isempty(app.unused_times)
                return;
            end
            
            last = app.unused_times(end);
            
            if (last < 0) &&  (-last < app.dah_space_threshold)
                app.unused_times(end) = [];
            end
            
            m = app.timings2morse(app.unused_times);
            
            if ~isempty(m)
                app.message = [app.message app.morse_decode(m)];
                app.morse_code = [app.morse_code m];
                app.ReceivedTextTextArea.Value = app.message;
                app.ReceivedMorseTextArea.Value = app.printable_morse(app.morse_code);
                app.SaveButton.Enable = true;
            end
            
            if last < 0
                app.unused_times = last;
            else
                app.unused_times = [];
            end
        end
            
        function dit_dah = timings2morse(app, times)
            dit_dah = [];
            
            for j = 1:length(times)
                d = times(j);
                
                if d > 0
                    if d < app.dit_dah_threshold
                        c = app.dit;
                    else
                        c = app.dah;
                    end
                else    
                    d = -d;
                    if d < app.dit_dah_threshold
                        c = '';
                    elseif d < app.dah_space_threshold
                        c = app.lettersep;
                    else
                        c = app.wordsep;
                    end
                end
                
                dit_dah = [dit_dah c];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dit_length = round((60000 / app.word_length) / app.WordsperMinuteSpinner.Value);
            app.dit_dah_threshold = dit_length * 2;
            app.dah_space_threshold = dit_length * 5;
            app.time_step = 1000 / (app.sampling_rate / app.samples_per_frame);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            txt = upper(strrep(char(app.TextTextArea.Value), '\n', ' '));
            
            if (length(txt) < 5) || ~(strcmp(txt(1:5), 'DRILL'))
                txt = ['DRILL '  txt];
            end
            
            txt = strrep(txt, '\n', ' ');
            
            morse = app.morse_encode(txt);
            app.MorseTextArea.Value = string(app.printable_morse(morse));
            app.TextTextArea.Value = string(txt);
            
            if strcmp(app.TextTextArea.Value, "")
                return;
            end
            
            signal = app.morse2wave(morse);
            plot(app.UIAxes, (0:length(signal)-1) / app.sampling_rate, signal);
            app.UIAxes.XLim = [0 length(signal)-1] / app.sampling_rate;
            app.UIAxes.Visible = true;
        end

        % Value changed function: ListenSwitch
        function ListenSwitchValueChanged(app, event)
            if app.ListenSwitch.Value == '1'
                app.listen_morse();
            else
                app.is_silent = true;
            end
        end

        % Close request function: MorseCodeUIFigure
        function MorseCodeUIFigureCloseRequest(app, event)
            if app.ListenSwitch.Value == '1'
                app.closing_req = true; 
                return
            end
            
            delete(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.message = '';
            app.morse_code = '';
            app.ReceivedTextTextArea.Value = "";
            app.ReceivedMorseTextArea.Value = "";
            app.SaveButton.Enable = false;
        end

        % Value changed function: WordsperMinuteSpinner
        function WordsperMinuteSpinnerValueChanged(app, event)
            dit_length = round((60000 / app.word_length) / app.WordsperMinuteSpinner.Value);
            app.dit_dah_threshold = dit_length * 2;
            app.dah_space_threshold = dit_length * 5;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.txt';'*.*'}, 'Save message', 'morse.txt');
            
            if filename
                fid = fopen([pathname filename], 'w');
                fprintf(fid, '%s', char(app.ReceivedTextTextArea.Value));
                fclose(fid);
                figure(app.MorseCodeUIFigure);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt';'*.*'}, 'Load message');
            
            if filename
                fid = fopen([pathname filename], 'r');
                app.TextTextArea.Value = fscanf(fid, '%c', Inf);
                fclose(fid);
                figure(app.MorseCodeUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MorseCodeUIFigure = uifigure('Visible', 'off');
            app.MorseCodeUIFigure.Name = 'Morse Code';
            app.MorseCodeUIFigure.CloseRequestFcn = createCallbackFcn(app, @MorseCodeUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MorseCodeUIFigure);
            app.SendTab = uitab(app.TabGroup);
            app.SendTab.Title = 'Send';
            app.UIAxes = uiaxes(app.SendTab);
            title(app.UIAxes, 'Morse signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (a.u.)')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1.2 1.2];
            app.SendButton = uibutton(app.SendTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.TextTextAreaLabel = uilabel(app.SendTab);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextTextArea = uitextarea(app.SendTab);
            app.MorseTextAreaLabel = uilabel(app.SendTab);
            app.MorseTextAreaLabel.Text = 'Morse';
            app.MorseTextArea = uitextarea(app.SendTab);
            app.MorseTextArea.Editable = 'off';
            app.WordsperMinuteSpinnerLabel = uilabel(app.SendTab);
            app.WordsperMinuteSpinnerLabel.Text = 'Words per Minute';
            app.WordsperMinuteSpinner = uispinner(app.SendTab);
            app.WordsperMinuteSpinner.Limits = [1 60];
            app.WordsperMinuteSpinner.RoundFractionalValues = 'on';
            app.WordsperMinuteSpinner.ValueChangedFcn = createCallbackFcn(app, @WordsperMinuteSpinnerValueChanged, true);
            app.WordsperMinuteSpinner.Value = 20;
            app.CarrierFrequencyLabel = uilabel(app.SendTab);
            app.CarrierFrequencyLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencySpinner = uispinner(app.SendTab);
            app.CarrierFrequencySpinner.Step = 50;
            app.CarrierFrequencySpinner.Limits = [200 5000];
            app.CarrierFrequencySpinner.RoundFractionalValues = 'on';
            app.CarrierFrequencySpinner.Value = 500;
            app.LoadButton = uibutton(app.SendTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ReceiveTab = uitab(app.TabGroup);
            app.ReceiveTab.Title = 'Receive';
            app.UIAxes2 = uiaxes(app.ReceiveTab);
            title(app.UIAxes2, 'Morse signal')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (a.u.)')
            app.ListenSwitchLabel = uilabel(app.ReceiveTab);
            app.ListenSwitchLabel.Text = 'Listen';
            app.ListenSwitch = uiswitch(app.ReceiveTab, 'slider');
            app.ListenSwitch.ItemsData = {'0', '1'};
            app.ListenSwitch.ValueChangedFcn = createCallbackFcn(app, @ListenSwitchValueChanged, true);
            app.ListenSwitch.Value = '0';
            app.ReceivedTextTextAreaLabel = uilabel(app.ReceiveTab);
            app.ReceivedTextTextAreaLabel.Text = 'Received Text';
            app.ReceivedTextTextArea = uitextarea(app.ReceiveTab);
            app.ReceivedTextTextArea.Editable = 'off';
            app.ReceivedMorseTextAreaLabel = uilabel(app.ReceiveTab);
            app.ReceivedMorseTextAreaLabel.Text = 'Received Morse';
            app.ReceivedMorseTextArea = uitextarea(app.ReceiveTab);
            app.ReceivedMorseTextArea.Editable = 'off';
            app.ClearButton = uibutton(app.ReceiveTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.ReceiveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.MorseCodeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorseCodeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorseCodeUIFigure)
        end
    end
end